classdef h8_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        L_1EditFieldLabel             matlab.ui.control.Label
        L_1EditField                  matlab.ui.control.NumericEditField
        L_2EditFieldLabel             matlab.ui.control.Label
        L_2EditField                  matlab.ui.control.NumericEditField
        m_1EditFieldLabel             matlab.ui.control.Label
        m_1EditField                  matlab.ui.control.NumericEditField
        F_1EditFieldLabel             matlab.ui.control.Label
        F_1EditField                  matlab.ui.control.NumericEditField
        F_2EditFieldLabel             matlab.ui.control.Label
        F_2EditField                  matlab.ui.control.NumericEditField
        w_1EditFieldLabel             matlab.ui.control.Label
        w_1EditField                  matlab.ui.control.NumericEditField
        w_2EditFieldLabel             matlab.ui.control.Label
        w_2EditField                  matlab.ui.control.NumericEditField
        m_2EditFieldLabel             matlab.ui.control.Label
        m_2EditField                  matlab.ui.control.NumericEditField
        ParametersLabel               matlab.ui.control.Label
        LengthofRodsLabel             matlab.ui.control.Label
        MassesLabel                   matlab.ui.control.Label
        ForceAmplitudeLabel           matlab.ui.control.Label
        ForceFrequencyLabel           matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        theta_1_1EditFieldLabel       matlab.ui.control.Label
        theta_1_1EditField            matlab.ui.control.NumericEditField
        theta_2_1EditFieldLabel       matlab.ui.control.Label
        theta_2_1EditField            matlab.ui.control.NumericEditField
        StartAnimationButton          matlab.ui.control.Button
        theta_1_2EditFieldLabel       matlab.ui.control.Label
        theta_1_2EditField            matlab.ui.control.NumericEditField
        theta_2_2EditFieldLabel       matlab.ui.control.Label
        theta_2_2EditField            matlab.ui.control.NumericEditField
        InitialConditionsLabel        matlab.ui.control.Label
        PositionDegreesLabel          matlab.ui.control.Label
        AngularVelocityDegreessLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartAnimationButton
        function StartAnimationButtonPushed(app, event)
            %define variables
            syms theta_1(t) theta_2(t) L_1 L_2 m_1 m_2 g F_1 w_1 F_2 w_2 T_1 T_2
            
            %define displacements
            x_1 = L_1*sin(theta_1);
            y_1 = -L_1*cos(theta_1);
            x_2 = x_1 + L_2*sin(theta_2);
            y_2 = y_1 - L_2*cos(theta_2);
            
            %differentiate displacement to get velocities
            vx_1 = diff(x_1);
            vy_1 = diff(y_1);
            vx_2 = diff(x_2);
            vy_2 = diff(y_2);
            
            %differentiate velocity to get acceleration
            ax_1 = diff(vx_1);
            ay_1 = diff(vy_1);
            ax_2 = diff(vx_2);
            ay_2 = diff(vy_2);
            
            %define equations of motion
            eqx_1 = m_1*ax_1(t) == -T_1*sin(theta_1(t)) + T_2*sin(theta_2(t)) + F_1*cos(w_1*t);
            eqy_1 = m_1*ay_1(t) == T_1*cos(theta_1(t)) - T_2*cos(theta_2(t)) - m_1*g;
            eqx_2 = m_2*ax_2(t) == -T_2*sin(theta_2(t)) + F_2*cos(w_2*t);
            eqy_2 = m_2*ay_2(t) == T_2*cos(theta_2(t)) - m_2*g;
            
            %evaluate tension and reduce equations
            Tension = solve([eqx_1 eqy_1],[T_1 T_2]);
            eqRed_1 = subs(eqx_2,[T_1 T_2],[Tension.T_1 Tension.T_2]);
            eqRed_2 = subs(eqy_2,[T_1 T_2],[Tension.T_1 Tension.T_2]);
            
            %define variables
            L_1 = app.L_1EditField.Value;
            L_2 = app.L_2EditField.Value;
            m_1 = app.m_1EditField.Value;
            m_2 = app.m_2EditField.Value;
            g = 9.8;
            F_1 = app.F_1EditField.Value;
            F_2 = app.F_2EditField.Value;
            w_1 = app.w_1EditField.Value;
            w_2 = app.w_2EditField.Value;
            
            %substitute values
            eqn_1 = subs(eqRed_1);
            eqn_2 = subs(eqRed_2);
            
            %convert to 1st order ODE
            [V,S] = odeToVectorField(eqn_1,eqn_2);
            M = matlabFunction(V,'vars',{'t','Y'});
            
            initCond = [app.theta_1_1EditField.Value*pi/180 app.theta_1_2EditField.Value*pi/180 app.theta_2_1EditField.Value*pi/180 app.theta_2_2EditField.Value*pi/180];
            sols = ode45(M,[0 10],initCond);
            
            %animate
            x_1 = @(t) L_1*sin(deval(sols,t,3));
            y_1 = @(t) -L_1*cos(deval(sols,t,3));
            x_2 = @(t) L_1*sin(deval(sols,t,3))+L_2*sin(deval(sols,t,1));
            y_2 = @(t) -L_1*cos(deval(sols,t,3))-L_2*cos(deval(sols,t,1));
            
            fanimator(@(t) plot(x_1(t),y_1(t),'ro','MarkerSize',m_1*10,'MarkerFaceColor','r'));
            axis equal;
            
            hold on;
            fanimator(@(t) plot([0 x_1(t)],[0 y_1(t)],'r-'));
            fanimator(@(t) plot(x_2(t),y_2(t),'go','MarkerSize',m_2*10,'MarkerFaceColor','g'));
            fanimator(@(t) plot([x_1(t) x_2(t)],[y_1(t) y_2(t)],'g-'));
            
            fanimator(@(t) text(-0.3,0.3,"Timer: "+num2str(t,2)));
            hold off;
            
            playAnimation
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {510, 510};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.L_1EditFieldLabel = uilabel(app.LeftPanel);
            app.L_1EditFieldLabel.Text = 'L_1';
            app.L_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.L_1EditField.Value = 1;
            app.L_2EditFieldLabel = uilabel(app.LeftPanel);
            app.L_2EditFieldLabel.Text = 'L_2';
            app.L_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.L_2EditField.Value = 1.5;
            app.m_1EditFieldLabel = uilabel(app.LeftPanel);
            app.m_1EditFieldLabel.Text = 'm_1';
            app.m_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.m_1EditField.Value = 2;
            app.F_1EditFieldLabel = uilabel(app.LeftPanel);
            app.F_1EditFieldLabel.Text = 'F_1';
            app.F_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.F_1EditField.Value = 20;
            app.F_2EditFieldLabel = uilabel(app.LeftPanel);
            app.F_2EditFieldLabel.Text = 'F_2';
            app.F_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.F_2EditField.Value = 1;
            app.w_1EditFieldLabel = uilabel(app.LeftPanel);
            app.w_1EditFieldLabel.Text = 'w_1';
            app.w_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.w_1EditField.Value = 5;
            app.w_2EditFieldLabel = uilabel(app.LeftPanel);
            app.w_2EditFieldLabel.Text = 'w_2';
            app.w_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.w_2EditField.Value = 0.4;
            app.m_2EditFieldLabel = uilabel(app.LeftPanel);
            app.m_2EditFieldLabel.Text = 'm_2';
            app.m_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.m_2EditField.Value = 1;
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.LengthofRodsLabel = uilabel(app.LeftPanel);
            app.LengthofRodsLabel.Text = 'Length of Rods';
            app.MassesLabel = uilabel(app.LeftPanel);
            app.MassesLabel.Text = 'Masses';
            app.ForceAmplitudeLabel = uilabel(app.LeftPanel);
            app.ForceAmplitudeLabel.Text = 'Force Amplitude';
            app.ForceFrequencyLabel = uilabel(app.LeftPanel);
            app.ForceFrequencyLabel.Text = 'Force Frequency';
            app.RightPanel = uipanel(app.GridLayout);
            app.theta_1_1EditFieldLabel = uilabel(app.RightPanel);
            app.theta_1_1EditFieldLabel.Text = 'theta_1_1';
            app.theta_1_1EditField = uieditfield(app.RightPanel, 'numeric');
            app.theta_1_1EditField.Value = 45;
            app.theta_2_1EditFieldLabel = uilabel(app.RightPanel);
            app.theta_2_1EditFieldLabel.Text = 'theta_2_1';
            app.theta_2_1EditField = uieditfield(app.RightPanel, 'numeric');
            app.theta_2_1EditField.Value = 30;
            app.StartAnimationButton = uibutton(app.RightPanel, 'push');
            app.StartAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnimationButtonPushed, true);
            app.StartAnimationButton.Text = 'Start Animation';
            app.theta_1_2EditFieldLabel = uilabel(app.RightPanel);
            app.theta_1_2EditFieldLabel.Text = 'theta_1_2';
            app.theta_1_2EditField = uieditfield(app.RightPanel, 'numeric');
            app.theta_2_2EditFieldLabel = uilabel(app.RightPanel);
            app.theta_2_2EditFieldLabel.Text = 'theta_2_2';
            app.theta_2_2EditField = uieditfield(app.RightPanel, 'numeric');
            app.InitialConditionsLabel = uilabel(app.RightPanel);
            app.InitialConditionsLabel.Text = 'Initial Conditions';
            app.PositionDegreesLabel = uilabel(app.RightPanel);
            app.PositionDegreesLabel.Text = 'Position (Degrees)';
            app.AngularVelocityDegreessLabel = uilabel(app.RightPanel);
            app.AngularVelocityDegreessLabel.Text = 'Angular Velocity (Degrees/s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = h8_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end